classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Dice_Similarity_Coefficient  matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        Jaccard_Index                matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        ButtonGroup                  matlab.ui.container.ButtonGroup
        C                            matlab.ui.control.RadioButton
        B                            matlab.ui.control.RadioButton
        A                            matlab.ui.control.RadioButton
        Image                        matlab.ui.control.Image
        Button                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            radioButtonClicked = app.ButtonGroup.SelectedObject.Text;
            [label1, label2] = main(radioButtonClicked);

            % Загрузка изображения из папки проекта
            projectFolder = pwd; % Получаем путь к текущей папке проекта
            imageFilename = 'segmented_image.png'; % Имя файла изображения
            imagePath = fullfile(projectFolder, imageFilename); % Полный путь к файлу
    
            % Загружаем изображение из указанного пути
            image2 = imread(imagePath);
            
            % Отображаем изображение в компоненте Image2
            app.Image2.ImageSource = image2;

            imageFilename = 'Discrete_Frechet_Distance_Map_for_Original_Image.png'; % Имя файла изображения
            imagePath = fullfile(projectFolder, imageFilename); % Полный путь к файлу
    
            % Загружаем изображение из указанного пути
            image3 = imread(imagePath);
            
            % Отображаем изображение в компоненте Image2
            app.Image3.ImageSource = image3;

            imageFilename = 'Discrete_Frechet_Distance_Map_for_Segmented_Image.png'; % Имя файла изображения
            imagePath = fullfile(projectFolder, imageFilename); % Полный путь к файлу
    
            % Загружаем изображение из указанного пути
            image4 = imread(imagePath);
            
            % Отображаем изображение в компоненте Image2
            app.Image4.ImageSource = image4;

            app.Jaccard_Index.Text = label1;
            app.Dice_Similarity_Coefficient.Text = label2;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сегментировать';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'img.jpg';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.A = uiradiobutton(app.ButtonGroup);
            app.A.Text = 'к-средних';
            app.A.Value = true;
            app.B = uiradiobutton(app.ButtonGroup);
            app.B.Text = 'водораздел';
            app.C = uiradiobutton(app.ButtonGroup);
            app.C.Text = 'адаптивная бинаризация';
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Сегментированное ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'изображение';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Карта дискретного ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'расстояния Фреше для';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'исходного изображения';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Карта дискретного ';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'расстояния Фреше для';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'исходного изображения';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Индекс Жаккара';
            app.Jaccard_Index = uilabel(app.UIFigure);
            app.Jaccard_Index.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'Коэффициент сходства Дайса';
            app.Dice_Similarity_Coefficient = uilabel(app.UIFigure);
            app.Dice_Similarity_Coefficient.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end